classdef ISSI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GoFocus                   matlab.ui.control.Button
        IncrementEditFieldLabel   matlab.ui.control.Label
        IncrementEditField        matlab.ui.control.NumericEditField
        LeftFocusButton           matlab.ui.control.Button
        RightFocusButton          matlab.ui.control.Button
        ReadbackEditField_2Label  matlab.ui.control.Label
        ReadbackEditField_2       matlab.ui.control.NumericEditField
        ReadbackEditFieldLabel    matlab.ui.control.Label
        ReadbackEditField         matlab.ui.control.NumericEditField
        SetpointEditFieldLabel    matlab.ui.control.Label
        SetpointEditField         matlab.ui.control.NumericEditField
        ISSILensControllerLabel   matlab.ui.control.Label
        LeftApertureButton        matlab.ui.control.Button
        RightApertureButton       matlab.ui.control.Button
        PresetsDropDownLabel      matlab.ui.control.Label
        PresetsDropDown           matlab.ui.control.DropDown
        GoPresetButton            matlab.ui.control.Button
        FocuscountsLabel          matlab.ui.control.Label
        AperturefLabel            matlab.ui.control.Label
        ContextMenu               matlab.ui.container.ContextMenu
        EditPresetsMenu           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CameraFocusValue % Description
        CameraAperture
        FocusPresets
        IOC_name
        Panel_name
    end
    
    properties (Access = public)
        ISSItimer
        BooleanMonitorValues
        ISSIPVs
    end
    
    methods (Access = private)
        function CheckMonitors(app)
            app.BooleanMonitorValues = lcaNewMonitorValue(app.ISSIPVs(1:2)) ;
            for i = 1:length(app.BooleanMonitorValues)
                if app.BooleanMonitorValues(i)
                    switch i
                        case 1
                            app.ReadbackEditField_2.Value = lcaGet(app.ISSIPVs(1));
                        case 2
                            app.ReadbackEditField.Value = lcaGet(app.ISSIPVs(2));
                    end
                end
            end
            if app.ReadbackEditField.Value == 2.8
                app.LeftApertureButton.Enable = 'off';
            elseif app.ReadbackEditField.Value == 32
                app.RightApertureButton.Enable = 'off';
            else
                app.LeftApertureButton.Enable = 'on';
                app.RightApertureButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IOCname, Panelname)
            addpath('/usr/local/EPICS_NEW/labca_3_7_2/bin/linux-x86_64/labca/')
            
            app.IOC_name = IOCname;
            app.Panel_name = Panelname;
            
            app.UIFigure.Name = [app.Panel_name ' ISSI Controls'];
            
            app.ISSIPVs = {...
                [app.IOC_name ':Focus_RBV']
                [app.IOC_name ':Aperture_RBV']
            };
            
            app.CameraFocusValue = lcaGet([app.IOC_name ':Focus_RBV']);
            app.ReadbackEditField_2.Value = app.CameraFocusValue;
            app.CameraAperture = lcaGet([app.IOC_name ':Aperture_RBV']);
            app.ReadbackEditField.Value = app.CameraAperture;
            
            app.ReadbackEditField_2.Editable = 'off';
            app.ReadbackEditField.Editable = 'off';
            
            lcaSetMonitor(app.ISSIPVs(1:2))

            app.ISSItimer = timer('Name', 'ISSItimer', ...
                'Period', 0.1, ...
                'StartDelay', 3, ...
                'TasksToExecute', inf, ...
                'ExecutionMode','FixedRate', ...
                'TimerFcn', @(varargin)CheckMonitors(app));
            
            % starts timer
            t_Timer = app.ISSItimer ;
            start(t_Timer)
        end

        % Button pushed function: GoFocus
        function GoFocusPushed(app, event)
            if app.SetpointEditField.Value > 16869 || app.SetpointEditField.Value < 0
                errordlg('You have reached a limit')
            else
                lcaPutNoWait([app.IOC_name ':Focus'],app.SetpointEditField.Value)
            end
        end

        % Button pushed function: LeftFocusButton
        function LeftFocusButtonPushed(app, event)
            newFocus = app.ReadbackEditField_2.Value - app.IncrementEditField.Value;
            if newFocus > 16869
                errordlg('You have reached a limit')
            else
                lcaPutNoWait([app.IOC_name ':Focus'],newFocus)
            end
        end

        % Button pushed function: RightFocusButton
        function RightFocusButtonPushed(app, event)
            newFocus = app.ReadbackEditField_2.Value + app.IncrementEditField.Value;
            if newFocus > 16869
                errordlg('You have reached a limit')
            else
                lcaPutNoWait([app.IOC_name ':Focus'],newFocus)
            end
        end

        % Button pushed function: LeftApertureButton
        function LeftApertureButtonPushed(app, event)
            app.LeftApertureButton.Enable = 'off';
            lcaPutNoWait([app.IOC_name ':ApertureTweak'],-1)
            pause(1)
            if app.ReadbackEditField.Value ~= 2.8
                 app.LeftApertureButton.Enable = 'on';
            end
        end

        % Button pushed function: RightApertureButton
        function RightApertureButtonPushed(app, event)
            app.RightApertureButton.Enable = 'off';
            lcaPutNoWait([app.IOC_name ':ApertureTweak'],1)
            pause(1)
            if app.ReadbackEditField.Value ~= 32
                app.RightApertureButton. Enable = 'on';
            end
        end

        % Button pushed function: GoPresetButton
        function GoPresetButtonPushed(app, event)
            FocusPresetsTextFileData = readcell([app.IOC_name '_Focus_Presets.txt']);
            app.FocusPresets = [FocusPresetsTextFileData{end,1:(length(FocusPresetsTextFileData)-1)}] ;
            for ii = 1:length(app.FocusPresets)
                if strcmp(app.PresetsDropDown.Value,app.PresetsDropDown.Items(ii))
                    if app.FocusPresets(ii) > 16869
                        errordlg('You have reached a limit')
                    else
                        lcaPutNoWait([app.IOC_name ':Focus'],app.FocusPresets(ii))
                    end
                end
            end
        end

        % Menu selected function: EditPresetsMenu
        function EditPresetsMenuSelected(app, event)
            ISSIgui = findall(0, 'Name', [app.Panel_name ' ISSI Edit Presets']);
            if ~isempty(ISSIgui) == 0
                ISSI_edit_presets(app.IOC_name, app.Panel_name)
            else
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear app.ISSItimer
            stop(app.ISSItimer)
            delete(app.ISSItimer) 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GoFocus = uibutton(app.UIFigure, 'push');
            app.GoFocus.ButtonPushedFcn = createCallbackFcn(app, @GoFocusPushed, true);
            app.GoFocus.Text = 'Go';
            app.IncrementEditFieldLabel = uilabel(app.UIFigure);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncrementEditField.Value = 1;
            app.LeftFocusButton = uibutton(app.UIFigure, 'push');
            app.LeftFocusButton.ButtonPushedFcn = createCallbackFcn(app, @LeftFocusButtonPushed, true);
            app.LeftFocusButton.Icon = 'left_arrow.png';
            app.LeftFocusButton.Text = '';
            app.RightFocusButton = uibutton(app.UIFigure, 'push');
            app.RightFocusButton.ButtonPushedFcn = createCallbackFcn(app, @RightFocusButtonPushed, true);
            app.RightFocusButton.Icon = 'right_arrow.png';
            app.RightFocusButton.Text = '';
            app.ReadbackEditField_2Label = uilabel(app.UIFigure);
            app.ReadbackEditField_2Label.Text = 'Readback';
            app.ReadbackEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ReadbackEditField_2.ValueDisplayFormat = '%.0f';
            app.ReadbackEditFieldLabel = uilabel(app.UIFigure);
            app.ReadbackEditFieldLabel.Text = 'Readback';
            app.ReadbackEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointEditField.ValueDisplayFormat = '%.0f';
            app.ISSILensControllerLabel = uilabel(app.UIFigure);
            app.ISSILensControllerLabel.Text = 'ISSI Lens Controller';
            app.LeftApertureButton = uibutton(app.UIFigure, 'push');
            app.LeftApertureButton.ButtonPushedFcn = createCallbackFcn(app, @LeftApertureButtonPushed, true);
            app.LeftApertureButton.Icon = 'left_arrow.png';
            app.LeftApertureButton.Text = '';
            app.RightApertureButton = uibutton(app.UIFigure, 'push');
            app.RightApertureButton.ButtonPushedFcn = createCallbackFcn(app, @RightApertureButtonPushed, true);
            app.RightApertureButton.Icon = 'right_arrow.png';
            app.RightApertureButton.Text = '';
            app.PresetsDropDownLabel = uilabel(app.UIFigure);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.UIFigure);
            app.PresetsDropDown.Items = {'P1', 'P2', 'P3', 'P4'};
            app.PresetsDropDown.Value = 'P1';
            app.GoPresetButton = uibutton(app.UIFigure, 'push');
            app.GoPresetButton.ButtonPushedFcn = createCallbackFcn(app, @GoPresetButtonPushed, true);
            app.GoPresetButton.Text = 'Go';
            app.FocuscountsLabel = uilabel(app.UIFigure);
            app.FocuscountsLabel.Text = 'Focus (counts)';
            app.AperturefLabel = uilabel(app.UIFigure);
            app.AperturefLabel.Text = 'Aperture (f#)';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.PresetsDropDown.ContextMenu = app.ContextMenu;
            app.EditPresetsMenu = uimenu(app.ContextMenu);
            app.EditPresetsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPresetsMenuSelected, true);
            app.EditPresetsMenu.Text = 'Edit Presets';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISSI_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end