classdef Interaction_point_YAG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        YAGPositioningLabel      matlab.ui.control.Label
        Panel_1                  matlab.ui.container.Panel
        Panel_2                  matlab.ui.container.Panel
        Panel_3                  matlab.ui.container.Panel
        Panel_4                  matlab.ui.container.Panel
        EbeamViewingCameraLabel  matlab.ui.control.Label
        Panel_5                  matlab.ui.container.Panel
        LaserViewingCameraLabel  matlab.ui.control.Label
        EbeamCameraPanel         matlab.ui.container.Panel
        LaserCameraPanel         matlab.ui.container.Panel
        Panel_6                  matlab.ui.container.Panel
        UIAxes_2                 matlab.ui.control.UIAxes
        Panel_7                  matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        testobj1 = [];
        testobj2 = [];
        testobj3 = [];
        testobj4 = [];
        testobj5 = [];
        cameraobj = [];
        cameraobj2 = [];
        TimerNames;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % makes parent GUI invisible while child GUI is initialized
            app.UIFigure.Visible = 'off';
            app.UIFigure.Name = 'Interaction Point YAG';
            % names of IOCs and panels that will be passed to child GUIs
            % to finish GUI, fill in proper IOC, Camera IOC, and timer
            % names
            IOCnames = ["MOTR:ICS1:20:H" "MOTR:ICS1:20:V" "MOTR:ICS1:20:Z" "MOTR:ICS1:120:PHI" "MOTR:ICS1:130:PHI"];
            Names = ["X-AXIS (mm)" "Y-AXIS (mm)" "Z-AXIS (mm)" "E-beam Theta-AXIS (deg)" "Laser Theta-AXIS (deg)"];
            app.TimerNames = "IP_YAG";
            ISSI_IOC = ["CAM:ICS1:20:LC" "CAM:ICS1:40:LC"];
            MCIOC = ["LED:ICS1:Bo2" "LED:ICS1:Bo4"]; %actual will be Bo1 and Bo3
            Camera_IOC = ["CAM:ICS1:20" "CAM:ICS1:40"];
            units = ["mm" "mm" "mm" "deg" "deg"];

            % Preset names that will be passed to child GUIs
            % N.B. If you want to change the number of presets, every line
            % in the file must have the same number of presets to work
            % properly. If you want to maintain a record of the file with a
            % different number of presets, duplicate and save the file.
            XPresetNames = {'P1 - 0.01','P2 - 0.02','P3 - 0.03','P4  - 0.05','P5 - 0.07','P6 - 0.1','P7 - 0.2','P8 - 0.5','P9 - 1','SMA - 1','YAG','OUT','CP1'};
            YPresetNames = {'OUT','IN','CP1'};
            ZPresetNames = {'OUT','IN','CP1'};
            BackThetaPresetNames = {'P1','P2','CP1','CP2'}; 
            FrontThetaPresetNames = {'P1','P2','CP1','CP2'};
            
            %combined into single cell that way we only need to index this
            %cell to use the set of preset names as an input argument
            Presets = {XPresetNames ; YPresetNames; ZPresetNames; BackThetaPresetNames; FrontThetaPresetNames};
            
            % creates structure to store elements of child GUIs
            hobjects(1:length(IOCnames)) = struct();
            camobjects = struct();
            camobjects2 = struct();
            
            % Loop sets property for child GUI to the child GUI, fills the
            % structure with the child GUI elements and passes IOC name to
            % child GUI
            for ii = 1:length(IOCnames)
                app.(['testobj' num2str(ii)]) = embed_GUI(0,Names{ii},Presets{ii},convertStringsToChars(IOCnames(ii)),convertStringsToChars(app.TimerNames),app.UIFigure.Name,convertStringsToChars(units(ii))); % test_embed is linked gui
                hobjects(ii).MyNewField = app.(['testobj' num2str(ii)]).embeddedapp.Children;   
            end
            
            % loads property for camera GUI, fills the struct with the GUI
            % elements and passes needed input arguments
            app.cameraobj = Camera_Panel(0,convertStringsToChars(Camera_IOC(1)),convertStringsToChars(MCIOC(1)),convertStringsToChars(ISSI_IOC(1)),'IP E-beam',app.UIAxes,"E_beam_View_cam");
            camobjects.MyNewField = app.cameraobj.camera_embed.Children;
            
            app.cameraobj2 = Camera_Panel(0,convertStringsToChars(Camera_IOC(2)),convertStringsToChars(MCIOC(2)),convertStringsToChars(ISSI_IOC(2)),'IP Laser',app.UIAxes_2,"Laser_View_cam");
            camobjects2.MyNewField = app.cameraobj2.camera_embed.Children;
            
           % now that child GUI is initialized, make parent GUI visible
           app.UIFigure.Visible = 'on';
           
           % puts camera GUI on parent GUI
           for idx = 1:length(camobjects)
                camobjects.MyNewField.Parent = app.EbeamCameraPanel;
           end
            
           % makes camera GUI alone invisible
            app.cameraobj.camera_embed.Visible = 'off';
            
            % puts camera GUI on parent GUI
            for idx = 1:length(camobjects2)
                camobjects2.MyNewField.Parent = app.LaserCameraPanel;
           end
            
           % makes camera GUI alone invisible
            app.cameraobj2.camera_embed.Visible = 'off';
            
            % loop puts child GUIs in panels on parent GUI and passes name
           % for the panel and IOC name to child GUI
           for iii = 1:length(IOCnames)
                for idx = 1: length((['hobjects' num2str(iii)]))
                    hobjects(iii).MyNewField.Parent = app.(['Panel_' num2str(iii)]);
                end
                
                % makes child GUIs as individual GUIs invisible
                app.(['testobj' num2str(iii)]).embeddedapp.Visible = 'off';
           end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % kills all timers when GUI closes
            app.testobj1.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj2.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj3.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj4.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj5.killTimer(convertStringsToChars(app.TimerNames));
            lasergui = findall(0,'Name', 'Laser Dashboard');
            app.cameraobj.killCameraTimerDual(lasergui,'CAM:ICS1:20',convertStringsToChars("E_beam_View_cam"));
            app.cameraobj.killCameraTimerDual(lasergui,'CAM:ICS1:20',convertStringsToChars("E_beam_View_cam_Image"));
            app.cameraobj2.killCameraTimerDual(lasergui,'CAM:ICS1:40',convertStringsToChars("Laser_View_cam"));
            app.cameraobj2.killCameraTimerDual(lasergui,'CAM:ICS1:40',convertStringsToChars("Laser_View_cam_Image"));
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Interaction point YAG.png';
            app.YAGPositioningLabel = uilabel(app.UIFigure);
            app.YAGPositioningLabel.Text = 'YAG Positioning';
            app.Panel_1 = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_4 = uipanel(app.UIFigure);
            app.EbeamViewingCameraLabel = uilabel(app.UIFigure);
            app.EbeamViewingCameraLabel.Text = 'E-beam Viewing Camera';
            app.Panel_5 = uipanel(app.UIFigure);
            app.LaserViewingCameraLabel = uilabel(app.UIFigure);
            app.LaserViewingCameraLabel.Text = 'Laser Viewing Camera';
            app.EbeamCameraPanel = uipanel(app.UIFigure);
            app.LaserCameraPanel = uipanel(app.UIFigure);
            app.Panel_6 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_6);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_7 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_7);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interaction_point_YAG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end