classdef embed_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        embeddedapp                     matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        PositionSetEditFieldLabel       matlab.ui.control.Label
        PositionSetEditField            matlab.ui.control.NumericEditField
        GoButton                        matlab.ui.control.Button
        PositionreadbackEditFieldLabel  matlab.ui.control.Label
        PositionreadbackEditField       matlab.ui.control.NumericEditField
        RightButton                     matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        IncrementEditFieldLabel         matlab.ui.control.Label
        IncrementEditField              matlab.ui.control.NumericEditField
        PresetPositionDropDownLabel     matlab.ui.control.Label
        PresetPositionDropDown          matlab.ui.control.DropDown
        GoPreset                        matlab.ui.control.Button
        MOREButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
        REFRESHButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Panel_name
        IOC_name
        IOCnameforMOREbutton
        TerminalText
        MotorPVList
        MotorPresets
        MotorCustomPresets
        BooleanMonitorValues
        moreIOCname
        Preset_names
        Timer_name
        GUIname
        MotorLowLimit
        MotorHighLimit
        MotorVelocity
        PasswordCorrect
        TweakDistance
        IR_Ent_Lens
        Montel_optic
        Laser_dash
        IR_Exit_Mirror
        IR_Exit_Lens
        IR_Ent_Mirror
        IP_YAG
        Free_Div_YAG
        Ebeam_Exit_YAG
        Ebeam_Ent_YAG
        Collimated_xray_YAG
        HomingDirection
    end
    
    properties (Access = private)
        moreGUI
        MotorPosition
        MotorStatus
        % all timers need to be a property defined here because of their
        % syntax and IOC name syntax
    end
    
    methods (Access = private)
        % If motor is moving, this function disables all buttons on the GUI
          function SetInteractivity(app,on_off)
            if on_off
                app.MotorStatus = 'Not Moving' ;
                app.GoPreset.Enable = 'on' ;
                app.GoButton.Enable = 'on' ;
                app.RightButton.Enable = 'on' ;
                app.LeftButton.Enable = 'on' ;
            else
                app.MotorStatus = 'Moving' ;
                app.GoPreset.Enable = 'off' ;
                app.GoButton.Enable = 'off' ;
                app.RightButton.Enable = 'off' ;
                app.LeftButton.Enable = 'off' ;                
            end
          end
        
        % checks if PVs have been changed outside GUI and updates on GUI  
        function CheckMonitors(app)
            app.BooleanMonitorValues = lcaNewMonitorValue(app.MotorPVList(2:4)) ;
            for i = 1:length(app.BooleanMonitorValues)
                if app.BooleanMonitorValues(i)
                    switch i
                        case 1
                            app.MotorVelocity = lcaGet(app.MotorPVList(2)) ;
                        case 2
                            if lcaGet(app.MotorPVList(3))
                                SetInteractivity(app,1)
                            else
                                SetInteractivity(app,0)
                            end
                        case 3
                            app.MotorPosition = lcaGet(app.MotorPVList(4)) ;
                            app.PositionreadbackEditField.Value = app.MotorPosition ;
                    end
                end
            end
%             disp('WORK')
        end
    end
    
        methods (Access = public)
            function deletefig(app)
                app.closecallback();
            end
        
            % this function puts the name of the axis in as the panel title
            function app = config(app,varargin)
                name = varargin{1};
%                 app.Panel_name.Interpreter = 'latex';
                app.Panel_name = name;
                app.Panel.Title = app.Panel_name;
            end
            function PasswordCheck(app)
                app.PasswordCorrect = 0;
                X = inputdlg('Enter Password');
    %             input = str2double(X{1,1});
                password = 'labuser'; % do we want a more secure password
                if strcmp(X, password)
                    app.PasswordCorrect = 1;
                else
                    app.PasswordCorrect = 0;
                end
            end
            function app = killTimer(app,Name)
              clear app.(Name)
              stop(app.(Name))
              delete(app.(Name))  
            end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initcallback(app, visible, names, preset_names, IOCnames, Timername, varargin)
            % needed to start labCA
            addpath('/usr/local/EPICS_NEW/labca_3_7_2/bin/linux-x86_64/labca/')
            
            % sets if the elements of panel are visible
            if visible == 1
                app.embeddedapp.Visible = 'on';
            else
                app.embeddedapp.Visible = 'off';
            end
            
            % gets IOC name
            app.IOC_name = IOCnames;
            app.IOCnameforMOREbutton = IOCnames ;
            app.Panel_name = names;
            app.Label.Text = names;
            
            % gets timer name
            app.Timer_name = Timername;
            app.GUIname = varargin{1};
            app.TweakDistance = varargin{2};
            app.HomingDirection = varargin{3};
            
            % changes preset dropdown menu list to items specified in
            % master GUI
            app.Preset_names = preset_names;
            app.PresetPositionDropDown.Items = preset_names ;
            
            % PVs for motor
            app.MotorPVList = {...
                [app.IOC_name '.RMP']
                [app.IOC_name '.VELO']
                [app.IOC_name '.DMOV']
                [app.IOC_name '.VAL']
                [app.IOC_name '.LLM']
                [app.IOC_name '.HLM']
                [app.IOC_name '.STOP']
                };   % - Controls What Picomotor PVs the GUI reads and writes to
            
            % gets initial position readback
            app.MotorPosition = lcaGet(app.MotorPVList(4)) ;
            app.PositionreadbackEditField.Value = app.MotorPosition ;
            app.PositionSetEditField.Value = app.MotorPosition;
            app.MotorLowLimit = lcaGet(app.MotorPVList(5));
            app.MotorHighLimit = lcaGet(app.MotorPVList(6));
            
            % determines if the motor is moving on startup to set
            % interactivity of motor
            if lcaGet(app.MotorPVList(3))
                SetInteractivity(app,1)
            else
                SetInteractivity(app,0)
            end
            
            % begins monitor
            lcaSetMonitor(app.MotorPVList(2:4))
            
            app.(convertStringsToChars(app.Timer_name)) = timer('Name', convertStringsToChars(app.Timer_name), ...
                'Period', 0.25, ...
                'StartDelay', 3, ...
                'TasksToExecute', inf, ...
                'ExecutionMode','FixedRate', ...
                'TimerFcn', @(varargin)CheckMonitors(app));
            
            % starts timer
            t_Timer = app.(convertStringsToChars(app.Timer_name)) ;
            start(t_Timer)

            app.embeddedapp.Visible = 'on'; % make app visible
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % moves motor to set position
            position = app.PositionSetEditField.Value;
            if(position > app.MotorHighLimit || position < app.MotorLowLimit)
                errordlg(['You have reached a limit for ' app.Panel_name '.' newline 'Motor has to stay between ' num2str(app.MotorLowLimit) ' and ' num2str(app.MotorHighLimit) '.'],'Motor Error')
            else
                lcaPutNoWait(app.MotorPVList(4),app.PositionSetEditField.Value)
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            % decreases motor position by increment
            newposition = (app.MotorPosition - app.IncrementEditField.Value);
            if(newposition > app.MotorHighLimit || newposition < app.MotorLowLimit)
                errordlg(['You have reached a limit for ' app.Panel_name '.' newline 'Motor has to stay between ' num2str(app.MotorLowLimit) ' and ' num2str(app.MotorHighLimit) '.'],'Motor Error')
            else
                lcaPutNoWait(app.MotorPVList(4),(app.MotorPosition - app.IncrementEditField.Value))
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            % increaes motor position by increment
            newposition = (app.MotorPosition + app.IncrementEditField.Value);
            if(newposition > app.MotorHighLimit || newposition < app.MotorLowLimit)
                errordlg(['You have reached a limit for ' app.Panel_name '.' newline 'Motor has to stay between ' num2str(app.MotorLowLimit) ' and ' num2str(app.MotorHighLimit) '.'],'Motor Error')
            else
                lcaPutNoWait(app.MotorPVList(4),(app.MotorPosition + app.IncrementEditField.Value))
            end
        end

        % Value changed function: PositionreadbackEditField
        function PositionreadbackEditFieldValueChanged(app, event)
            % sets readback field to update
            app.PositionreadbackEditField.Value = app.MotorPVList(4);
        end

        % Close request function: embeddedapp
        function closecallback(app, event)
            % closes GUI and ends timer when close parent GUI
%             stop(app.([convertStringsToChars(app.Timer_name) '_timer']))
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % stops motor when button pushed
            oldaccl = lcaGet([app.IOC_name '.ACCL']);
            lcaPutNoWait([app.IOC_name  '.ACCL'],0.5)
            lcaPut(app.MotorPVList(7),1)
            lcaPutNoWait([app.IOC_name  '.ACCL'],oldaccl)
        end

        % Button pushed function: GoPreset
        function GoPresetPushed(app, event)
            % moves motor to preset position, first finds to which preset
            % user wants to move motor then finds the corresponding preset
            % position with the nested if statement and moves the motor
            % load files when press go so that updates are considered
            
            % loads from file for standard presets
            PresetsTextFileData = readcell([app.IOC_name '_Presets.txt']);
            app.MotorPresets = [PresetsTextFileData{end,1:(length(PresetsTextFileData(end,:))-1)}] ;
            clear PresetsTextFileData
            
            % loads from file for custom presets
            CustomPresetsTextFileData = readcell([app.IOC_name '_Custom_Presets.txt']);
            app.MotorCustomPresets = [CustomPresetsTextFileData{end,1:(length(CustomPresetsTextFileData(end,:))-1)}] ;
            clear CustomPresetsTextFileData
            
            % disables GUI buttons and determines to which preset to move
            % the motor
            SetInteractivity(app,0)
            for ii = 1:length(app.PresetPositionDropDown.Items)
                if strcmp(app.PresetPositionDropDown.Value,app.PresetPositionDropDown.Items(ii))
                    index = ii ;
                    % need if/else statements to determine standard or
                    % custom presets
                    % From Greg: this is that if/else statement
                    if index <= length(app.MotorPresets)
                        lcaPutNoWait(app.MotorPVList(4),app.MotorPresets(index))
                    else
                        lcaPutNoWait(app.MotorPVList(4),app.MotorCustomPresets(index-length(app.MotorPresets)))
                    end
                else
                end
            end
        end

        % Button pushed function: MOREButton
        function MOREButtonPushed(app, event)
            % opens MORE GUI and passes IOC and preset names to it
            Moregui = findall(0, 'Name', [app.GUIname ' ' app.Panel_name ' MORE']);
            if ~isempty(Moregui) == 0
                app.moreGUI = MORE_GUI(app.IOCnameforMOREbutton,app.Preset_names,app.GUIname,app.Panel_name,app.TweakDistance,app.HomingDirection); 
            else
            end
        end

        % Button pushed function: REFRESHButton
        function REFRESHButtonPushed(app, event)
            PasswordCheck(app)
            if app.PasswordCorrect
                app.MotorLowLimit = lcaGet(app.MotorPVList(5));
                app.MotorHighLimit = lcaGet(app.MotorPVList(6));
                app.MotorVelocity = lcaGet(app.MotorPVList(2));
                app.MotorPosition = lcaGet(app.MotorPVList(4));
                app.PositionreadbackEditField.Value = app.MotorPosition;
           else
                errordlg('Incorrect password')
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.embeddedapp = uifigure('Visible', 'off');
            app.embeddedapp.Name = 'MATLAB App';
            app.embeddedapp.CloseRequestFcn = createCallbackFcn(app, @closecallback, true);
            app.Panel = uipanel(app.embeddedapp);
            app.PositionSetEditFieldLabel = uilabel(app.Panel);
            app.PositionSetEditFieldLabel.Text = 'Position Set';
            app.PositionSetEditField = uieditfield(app.Panel, 'numeric');
            app.PositionSetEditField.ValueDisplayFormat = '%.3f';
            app.GoButton = uibutton(app.Panel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.PositionreadbackEditFieldLabel = uilabel(app.Panel);
            app.PositionreadbackEditFieldLabel.Text = 'Position readback';
            app.PositionreadbackEditField = uieditfield(app.Panel, 'numeric');
            app.PositionreadbackEditField.ValueDisplayFormat = '%.3f';
            app.PositionreadbackEditField.ValueChangedFcn = createCallbackFcn(app, @PositionreadbackEditFieldValueChanged, true);
            app.PositionreadbackEditField.Editable = 'off';
            app.RightButton = uibutton(app.Panel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Icon = 'right_arrow.png';
            app.RightButton.Text = '';
            app.LeftButton = uibutton(app.Panel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Icon = 'left_arrow.png';
            app.LeftButton.Text = '';
            app.IncrementEditFieldLabel = uilabel(app.Panel);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.Panel, 'numeric');
            app.IncrementEditField.ValueDisplayFormat = '%.3f';
            app.PresetPositionDropDownLabel = uilabel(app.Panel);
            app.PresetPositionDropDownLabel.Text = 'Preset Position';
            app.PresetPositionDropDown = uidropdown(app.Panel);
            app.PresetPositionDropDown.Items = {'P1', 'P2', 'P3', 'P4', 'P5', 'OUT', 'YAG', 'CP1', 'CP2', 'CP3', 'CP4', 'CP5'};
            app.PresetPositionDropDown.Value = 'P1';
            app.GoPreset = uibutton(app.Panel, 'push');
            app.GoPreset.ButtonPushedFcn = createCallbackFcn(app, @GoPresetPushed, true);
            app.GoPreset.Text = 'Go';
            app.MOREButton = uibutton(app.Panel, 'push');
            app.MOREButton.ButtonPushedFcn = createCallbackFcn(app, @MOREButtonPushed, true);
            app.MOREButton.Text = '- MORE -';
            app.Label = uilabel(app.Panel);
            app.STOPButton = uibutton(app.Panel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.REFRESHButton = uibutton(app.Panel, 'push');
            app.REFRESHButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHButtonPushed, true);
            app.REFRESHButton.Text = 'REFRESH';
            % Show the figure after all components are created
            app.embeddedapp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = embed_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.embeddedapp)

            % Execute the startup function
            runStartupFcn(app, @(app)initcallback(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.embeddedapp)
        end
    end
end